classdef app_beta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Tab                            matlab.ui.container.Tab
        UIAxes_histogram               matlab.ui.control.UIAxes
        Tab_2                          matlab.ui.container.Tab
        UIAxes_histogram_equalization  matlab.ui.control.UIAxes
        Label_filepath                 matlab.ui.control.Label
        button_readimage               matlab.ui.control.Button
        UIAxes_image_gray              matlab.ui.control.UIAxes
        UIAxes_image                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        image % Description
        fname % path
    end
    
    methods (Access = public)
        function data = getgraydata(app, image)
            data = zeros(256,1);
            [h,w] = size(image);
            for x = 1:h
               for y = 1:w
                   data(image(x,y)+1) = data(image(x,y)+1) + 1;
               end
            end
        end

        function data_proved = getequalizationdata(app, image)
            data = imhist(image);
            [h,w] = size(image);
            newimage = zeros(h,w);
            s = zeros(256);
            s(1) = data(1);
            for t = 2:256
                s(t) = s(t-1) + data(t);
            end
            for x = 1:w
                for y = 1:h
                    newimage(y,x) = s(image(y,x)+1)/(h*w);
                end
            end
            data_proved = imhist(newimage);
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: button_readimage
        function button_readimageButtonPushed(app, event)
            [f,p]=uigetfile({'*.jpg;*.tif;*.png;*.gif;*.bmp','All Image Files'});  
            if (ischar(p))
               fname = [p f];
               app.Label_filepath.Text = fname;
               image = imread(fname);
               imshow(image,'parent',app.UIAxes_image);
               image = rgb2gray(image);
               imshow(image,'Parent',app.UIAxes_image_gray);

               data = getgraydata(app, image);
               bar(app.UIAxes_histogram,data);

               data_equal = getequalizationdata(app,image);
               bar(app.UIAxes_histogram_equalization,data_equal);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_image = uiaxes(app.UIFigure);
            title(app.UIAxes_image, '原图')
            xlabel(app.UIAxes_image, 'X')
            ylabel(app.UIAxes_image, 'Y')
            zlabel(app.UIAxes_image, 'Z')
            app.UIAxes_image_gray = uiaxes(app.UIFigure);
            title(app.UIAxes_image_gray, '灰度化')
            xlabel(app.UIAxes_image_gray, 'X')
            ylabel(app.UIAxes_image_gray, 'Y')
            zlabel(app.UIAxes_image_gray, 'Z')
            app.button_readimage = uibutton(app.UIFigure, 'push');
            app.button_readimage.ButtonPushedFcn = createCallbackFcn(app, @button_readimageButtonPushed, true);
            app.button_readimage.Text = '导入图片';
            app.Label_filepath = uilabel(app.UIFigure);
            app.Label_filepath.Text = 'pathname';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '灰度直方图';
            app.UIAxes_histogram = uiaxes(app.Tab);
            title(app.UIAxes_histogram, 'Title')
            xlabel(app.UIAxes_histogram, 'X')
            ylabel(app.UIAxes_histogram, 'Y')
            zlabel(app.UIAxes_histogram, 'Z')
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '直方图均衡化';
            app.UIAxes_histogram_equalization = uiaxes(app.Tab_2);
            title(app.UIAxes_histogram_equalization, 'Title')
            xlabel(app.UIAxes_histogram_equalization, 'X')
            ylabel(app.UIAxes_histogram_equalization, 'Y')
            zlabel(app.UIAxes_histogram_equalization, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_beta

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end